classdef FINAL_PPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Nghiemtab                      matlab.ui.container.Tab
        KetQuaNghiem                   matlab.ui.control.EditField
        KtqunghimEditFieldLabel_2      matlab.ui.control.Label
        PhuongTrinh_2                  matlab.ui.control.EditField
        Nhpphngtrnh2Label              matlab.ui.control.Label
        NhpkhongphnlynghimLabel        matlab.ui.control.Label
        PhanLyA                        matlab.ui.control.NumericEditField
        ChonPPNghiem                   matlab.ui.control.DropDown
        ChnphngphptmnghimLabel         matlab.ui.control.Label
        SoLanLap                       matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel_2          matlab.ui.control.Label
        SaiSo                          matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel_2  matlab.ui.control.Label
        PhuongTrinh                    matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel_2    matlab.ui.control.Label
        TimNghiem                      matlab.ui.control.Button
        PhanLyB                        matlab.ui.control.NumericEditField
        DoThiNghiem                    matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        Tinh_NSButton                  matlab.ui.control.Button
        KQ_NSLabel                     matlab.ui.control.Label
        GTcannoisuyEditField           matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        KQdathuc_NSEditField           matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel    matlab.ui.control.Label
        PP_NSDropDown                  matlab.ui.control.DropDown
        ChnphngphpnisuyDropDownLabel   matlab.ui.control.Label
        Nhapy_NSEditField              matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2       matlab.ui.control.Label
        Nhapx_NSEditField              matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_2       matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        HoiquyTab                      matlab.ui.container.Tab
        Tinh_HQButton                  matlab.ui.control.Button
        KQ_HQLabel                     matlab.ui.control.Label
        GTcandudoan_HQEditField        matlab.ui.control.NumericEditField
        GitrcndonEditFieldLabel        matlab.ui.control.Label
        KQPT_HQEditField               matlab.ui.control.EditField
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        PP_HQDropDown                  matlab.ui.control.DropDown
        ChnphngphphiquyDropDownLabel   matlab.ui.control.Label
        Nhapy_HQEditField              matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        Nhapx_HQEditField              matlab.ui.control.EditField
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        DaoHamTab                      matlab.ui.container.Tab
        ChonGTSaiSoDropDown            matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel       matlab.ui.control.Label
        ChonPPDaoHamDropDown           matlab.ui.control.DropDown
        ChnphngphpDropDownLabel        matlab.ui.control.Label
        KQDaoHamLabel                  matlab.ui.control.Label
        Tab4Button                     matlab.ui.control.Button
        GtcandaohamEditField           matlab.ui.control.NumericEditField
        NhpgitrcntnhohmEditFieldLabel  matlab.ui.control.Label
        Nhap_hEditField                matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel           matlab.ui.control.Label
        Nhap_hamEditField              matlab.ui.control.EditField
        NhphmsEditFieldLabel           matlab.ui.control.Label
        TichphanTab                    matlab.ui.container.Tab
        KQTichphanLabel                matlab.ui.control.Label
        NhapcanCuoiEditField           matlab.ui.control.NumericEditField
        NhpCncuiEditFieldLabel         matlab.ui.control.Label
        TinhtichphanButton             matlab.ui.control.Button
        NhapNEditField                 matlab.ui.control.NumericEditField
        NhpNEditFieldLabel             matlab.ui.control.Label
        NhapcanDauEditField            matlab.ui.control.NumericEditField
        NhpCnuLabel                    matlab.ui.control.Label
        PPT_TichphanDropDown           matlab.ui.control.DropDown
        ChnphngphptchphnDropDownLabel  matlab.ui.control.Label
        Nhapham_TichphanEditField      matlab.ui.control.EditField
        NhphmsEditFieldLabel_2         matlab.ui.control.Label
        GioithieuTab                   matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        CmnthyxembilmcanhmemLabel      matlab.ui.control.Label
        EditField_3                    matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        EditField                      matlab.ui.control.EditField
        THNHVINNHM9Label               matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Tab4Button
        function Tab4ButtonPushed(app, event)
             % Lấy giá trị từ UI controls
            fx = app.Nhap_hamEditField.Value;
            x = app.GtcandaohamEditField.Value;
            h = app.Nhap_hEditField.Value;
            pp = app.ChonPPDaoHamDropDown.Value;
            ss = app.ChonGTSaiSoDropDown.Value;

            %dx = DaoHamXapXiTrungTam(fx, x, h);
            dx = TinhDaoHam(fx, x, h, pp);
            dy = daohamOh(fx, x, h, pp);

            switch ss
                case 'O(h^2)'
                    app.KQDaoHamLabel.Text = num2str(dx);
                otherwise
                    app.KQDaoHamLabel.Text = num2str(dy);
            end
        end

        % Button pushed function: TinhtichphanButton
        function TinhtichphanButtonPushed(app, event)
            % Lấy giá trị từ UI controls
            fx = app.Nhapham_TichphanEditField.Value;
            a = app.NhapcanDauEditField.Value;
            b = app.NhapcanCuoiEditField.Value;
            N = app.NhapNEditField.Value;
            tp = app.PPT_TichphanDropDown.Value;
            switch tp
                case 'Hình thang'
                    y = TichPhanHinhThang(fx, a, b, N);
                    app.KQTichphanLabel.Text = num2str(y);
                case 'Simpson 1/3'   
                    y = TichPhanSimpson13(fx, a, b, N);
                    app.KQTichphanLabel.Text = num2str(y);
                otherwise
                    y = TichPhanSimpson38(fx, a, b, N);
                    app.KQTichphanLabel.Text = num2str(y);
            end
            

        end

        % Button pushed function: Tinh_HQButton
        function Tinh_HQButtonPushed(app, event)
            x = [str2num(app.Nhapx_HQEditField.Value)];
            y = [str2num(app.Nhapy_HQEditField.Value)];
                       
            hq = app.GTcandudoan_HQEditField.Value;
            pp = app.PP_HQDropDown.Value;

            n = length(x);
            x1 = linspace(x(1), x(n), 100);

            [KQ, PT, A, B] = HoiQuy(x, y, n, x1, hq, pp);

            cla(app.UIAxes2);
            grid(app.UIAxes2, 'on');
            plot(app.UIAxes2, x, y, 'bo', x1, PT, 'g-', hq, KQ, 'r*');         
            
            app.KQPT_HQEditField.Value = ['a = ' num2str(A) '; b = ' num2str(B)];
            app.KQ_HQLabel.Text = num2str(KQ);
        end

        % Button pushed function: Tinh_NSButton
        function Tinh_NSButtonPushed(app, event)
            x = [str2num(app.Nhapx_NSEditField.Value)];
            y = [str2num(app.Nhapy_NSEditField.Value)];
            
            ns = app.GTcannoisuyEditField.Value;
            pp = app.PP_NSDropDown.Value;

            n = length(x);
            x1 = linspace(x(1), x(n), 100);

            [KQ, PT, d] = NoiSuy(x, y, n, x1, ns, pp);

            cla(app.UIAxes3);
            grid(app.UIAxes3, 'on');
            plot(app.UIAxes3, x, y, 'bo', x1, PT, 'g-', ns, KQ, 'r*');

            PT=[];
            for i=1:n
                if(d(i)~=0)
                    if(i==n)
                        D = num2str(d(i));
                    elseif(i==n-1)
                        D = strcat(num2str(d(i)), 'x');
                    else
                        D = strcat(num2str(d(i)), 'x^', num2str(n-i));
                    end
                    if(i>1 && d(i)>0)
                        PT = strcat(PT,'+',D);
                    else
                        PT = strcat(PT,D);
                    end
                end
            end

            app.KQdathuc_NSEditField.Value = num2str(PT);
            app.KQ_NSLabel.Text = num2str(KQ);
        end

        % Button pushed function: TimNghiem
        function TimNghiemButtonPushed(app, event)
            fx = matlabFunction(str2sym(app.PhuongTrinh.Value));
            fp = matlabFunction(str2sym(app.PhuongTrinh_2.Value));

            a = app.PhanLyA.Value;
            b = app.PhanLyB.Value;
            es = app.SaiSo.Value;
            pp = app.ChonPPNghiem.Value;

            [KQ, n, x] = TimNghiem(fx, fp, a, b, es, pp);

            cla(app.DoThiNghiem);
            grid(app.DoThiNghiem, 'on');
            hold(app.DoThiNghiem, 'on');
            plot(app.DoThiNghiem, x, zeros(1, 100), 'k');
            if(pp ~= "Chia đôi")
                plot(app.DoThiNghiem, zeros(1, 100), x, 'k');
            end
            if(pp == "Lặp")
                plot(app.DoThiNghiem, x, fp(x),'b-', x, x,'g-', KQ, fp(KQ), 'r*');
            else
                plot(app.DoThiNghiem, x, fx(x),'b-', KQ, fx(KQ), 'r*');
            end

            app.KetQuaNghiem.Value = num2str(KQ);
            app.SoLanLap.Value = n;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Nghiemtab = uitab(app.TabGroup);
            app.Nghiemtab.Title = 'Nghiệm';
            app.DoThiNghiem = uiaxes(app.Nghiemtab);
            title(app.DoThiNghiem, 'Vẽ đồ thị hàm số')
            xlabel(app.DoThiNghiem, 'X')
            ylabel(app.DoThiNghiem, 'Y')
            zlabel(app.DoThiNghiem, 'Z')
            app.PhanLyB = uieditfield(app.Nghiemtab, 'numeric');
            app.TimNghiem = uibutton(app.Nghiemtab, 'push');
            app.TimNghiem.ButtonPushedFcn = createCallbackFcn(app, @TimNghiemButtonPushed, true);
            app.TimNghiem.Text = 'Tìm Nghiệm';
            app.NhpphngtrnhEditFieldLabel_2 = uilabel(app.Nghiemtab);
            app.NhpphngtrnhEditFieldLabel_2.Text = 'Nhập phương trình';
            app.PhuongTrinh = uieditfield(app.Nghiemtab, 'text');
            app.NhpsaischophpEditFieldLabel_2 = uilabel(app.Nghiemtab);
            app.NhpsaischophpEditFieldLabel_2.Text = 'Nhập sai số cho phép';
            app.SaiSo = uieditfield(app.Nghiemtab, 'numeric');
            app.SlnlpEditFieldLabel_2 = uilabel(app.Nghiemtab);
            app.SlnlpEditFieldLabel_2.Text = 'Số lần lặp';
            app.SoLanLap = uieditfield(app.Nghiemtab, 'numeric');
            app.ChnphngphptmnghimLabel = uilabel(app.Nghiemtab);
            app.ChnphngphptmnghimLabel.Text = 'Chọn phương pháp tìm nghiệm';
            app.ChonPPNghiem = uidropdown(app.Nghiemtab);
            app.ChonPPNghiem.Items = {'Chia đôi', 'Lặp', 'Newton (Tiếp tuyến)'};
            app.ChonPPNghiem.Value = 'Chia đôi';
            app.PhanLyA = uieditfield(app.Nghiemtab, 'numeric');
            app.NhpkhongphnlynghimLabel = uilabel(app.Nghiemtab);
            app.NhpkhongphnlynghimLabel.Text = 'Nhập khoảng phân ly';
            app.Nhpphngtrnh2Label = uilabel(app.Nghiemtab);
            app.Nhpphngtrnh2Label.Text = 'Nhập phương trình 2';
            app.PhuongTrinh_2 = uieditfield(app.Nghiemtab, 'text');
            app.KtqunghimEditFieldLabel_2 = uilabel(app.Nghiemtab);
            app.KtqunghimEditFieldLabel_2.Text = 'Kết quả nghiệm';
            app.KetQuaNghiem = uieditfield(app.Nghiemtab, 'text');
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes3 = uiaxes(app.NisuyTab);
            title(app.UIAxes3, 'Đồ thị hàm số nội suy và dữ liệu thật')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x';
            app.Nhapx_NSEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y';
            app.Nhapy_NSEditField = uieditfield(app.NisuyTab, 'text');
            app.ChnphngphpnisuyDropDownLabel = uilabel(app.NisuyTab);
            app.ChnphngphpnisuyDropDownLabel.Text = 'Chọn phương pháp nội suy';
            app.PP_NSDropDown = uidropdown(app.NisuyTab);
            app.PP_NSDropDown.Items = {'Newton', 'Lagrange'};
            app.PP_NSDropDown.Value = 'Newton';
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KQdathuc_NSEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.GTcannoisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KQ_NSLabel = uilabel(app.NisuyTab);
            app.KQ_NSLabel.Text = 'Kết quả nội suy';
            app.Tinh_NSButton = uibutton(app.NisuyTab, 'push');
            app.Tinh_NSButton.ButtonPushedFcn = createCallbackFcn(app, @Tinh_NSButtonPushed, true);
            app.Tinh_NSButton.Text = 'Tính nội suy';
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.Title = 'Hồi quy';
            app.UIAxes2 = uiaxes(app.HoiquyTab);
            title(app.UIAxes2, 'Đồ thị hàm số hồi quy và dữ liệu thật')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.HoiquyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.Nhapx_HQEditField = uieditfield(app.HoiquyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.HoiquyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.Nhapy_HQEditField = uieditfield(app.HoiquyTab, 'text');
            app.ChnphngphphiquyDropDownLabel = uilabel(app.HoiquyTab);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.PP_HQDropDown = uidropdown(app.HoiquyTab);
            app.PP_HQDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.PP_HQDropDown.Value = 'Tuyến tính';
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.HoiquyTab);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy';
            app.KQPT_HQEditField = uieditfield(app.HoiquyTab, 'text');
            app.GitrcndonEditFieldLabel = uilabel(app.HoiquyTab);
            app.GitrcndonEditFieldLabel.Text = 'Giá trị cần dự đoán';
            app.GTcandudoan_HQEditField = uieditfield(app.HoiquyTab, 'numeric');
            app.KQ_HQLabel = uilabel(app.HoiquyTab);
            app.KQ_HQLabel.Text = 'Kết quả dự đoán';
            app.Tinh_HQButton = uibutton(app.HoiquyTab, 'push');
            app.Tinh_HQButton.ButtonPushedFcn = createCallbackFcn(app, @Tinh_HQButtonPushed, true);
            app.Tinh_HQButton.Text = 'Tính hồi quy';
            app.DaoHamTab = uitab(app.TabGroup);
            app.DaoHamTab.Title = 'Đạo hàm';
            app.NhphmsEditFieldLabel = uilabel(app.DaoHamTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.Nhap_hamEditField = uieditfield(app.DaoHamTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.DaoHamTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.Nhap_hEditField = uieditfield(app.DaoHamTab, 'numeric');
            app.NhpgitrcntnhohmEditFieldLabel = uilabel(app.DaoHamTab);
            app.NhpgitrcntnhohmEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.GtcandaohamEditField = uieditfield(app.DaoHamTab, 'numeric');
            app.Tab4Button = uibutton(app.DaoHamTab, 'push');
            app.Tab4Button.ButtonPushedFcn = createCallbackFcn(app, @Tab4ButtonPushed, true);
            app.Tab4Button.Text = 'Tính Đạo Hàm';
            app.KQDaoHamLabel = uilabel(app.DaoHamTab);
            app.ChnphngphpDropDownLabel = uilabel(app.DaoHamTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.ChonPPDaoHamDropDown = uidropdown(app.DaoHamTab);
            app.ChonPPDaoHamDropDown.Items = {'Tiến', 'Lùi', 'Trung Tâm'};
            app.ChonPPDaoHamDropDown.Value = 'Tiến';
            app.ChngitrsaisDropDownLabel = uilabel(app.DaoHamTab);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.ChonGTSaiSoDropDown = uidropdown(app.DaoHamTab);
            app.ChonGTSaiSoDropDown.Items = {'O(h)', 'O(h^2)'};
            app.ChonGTSaiSoDropDown.Value = 'O(h)';
            app.TichphanTab = uitab(app.TabGroup);
            app.TichphanTab.Title = 'Tích phân';
            app.NhphmsEditFieldLabel_2 = uilabel(app.TichphanTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.Nhapham_TichphanEditField = uieditfield(app.TichphanTab, 'text');
            app.ChnphngphptchphnDropDownLabel = uilabel(app.TichphanTab);
            app.ChnphngphptchphnDropDownLabel.Text = 'Chọn phương pháp tích phân';
            app.PPT_TichphanDropDown = uidropdown(app.TichphanTab);
            app.PPT_TichphanDropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PPT_TichphanDropDown.Value = 'Hình thang';
            app.NhpCnuLabel = uilabel(app.TichphanTab);
            app.NhpCnuLabel.Text = 'Nhập Cận đầu';
            app.NhapcanDauEditField = uieditfield(app.TichphanTab, 'numeric');
            app.NhpNEditFieldLabel = uilabel(app.TichphanTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.NhapNEditField = uieditfield(app.TichphanTab, 'numeric');
            app.TinhtichphanButton = uibutton(app.TichphanTab, 'push');
            app.TinhtichphanButton.ButtonPushedFcn = createCallbackFcn(app, @TinhtichphanButtonPushed, true);
            app.TinhtichphanButton.Text = 'Tính tích phân';
            app.NhpCncuiEditFieldLabel = uilabel(app.TichphanTab);
            app.NhpCncuiEditFieldLabel.Text = 'Nhập Cận cuối';
            app.NhapcanCuoiEditField = uieditfield(app.TichphanTab, 'numeric');
            app.KQTichphanLabel = uilabel(app.TichphanTab);
            app.KQTichphanLabel.Text = 'Kết quả';
            app.GioithieuTab = uitab(app.TabGroup);
            app.GioithieuTab.Title = 'Giới thiệu nhóm';
            app.THNHVINNHM9Label = uilabel(app.GioithieuTab);
            app.THNHVINNHM9Label.Text = 'THÀNH VIÊN NHÓM 9';
            app.EditField = uieditfield(app.GioithieuTab, 'text');
            app.EditField.Value = 'Đinh Hồ Phúc Thiện - 21207102 - Nhóm Trưởng';
            app.EditField_2 = uieditfield(app.GioithieuTab, 'text');
            app.EditField_2.Value = 'Nguyễn Phương Nhật Hiền - 21207152';
            app.EditField_3 = uieditfield(app.GioithieuTab, 'text');
            app.EditField_3.Value = 'Trương Quang Minh - 21207062';
            app.CmnthyxembilmcanhmemLabel = uilabel(app.GioithieuTab);
            app.CmnthyxembilmcanhmemLabel.Interpreter = 'tex';
            app.CmnthyxembilmcanhmemLabel.Text = 'Cảm ơn thầy đã xem bài làm của nhóm em';
            app.Image = uiimage(app.GioithieuTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'OIP.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINAL_PPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end